classdef Converter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UnitConverterLabel            matlab.ui.control.Label
        ConvertButton                 matlab.ui.control.Button
        ResultEditField               matlab.ui.control.NumericEditField
        ResultEditFieldLabel          matlab.ui.control.Label
        InputEditField                matlab.ui.control.NumericEditField
        InputEditFieldLabel           matlab.ui.control.Label
        ToDropDown                    matlab.ui.control.DropDown
        ToDropDownLabel               matlab.ui.control.Label
        FromDropDown                  matlab.ui.control.DropDown
        FromDropDownLabel             matlab.ui.control.Label
        UnitMeasurementDropDown       matlab.ui.control.DropDown
        UnitMeasurementDropDownLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: UnitMeasurementDropDown
        function UnitMeasurementDropDownValueChanged(app, event)
            % Update FromUnitDropDown and ToUnitDropDown options based on selected category
            UnitMeasurement = app.UnitMeasurementDropDown.Value;
            % Update FromUnitDropDown and ToUnitDropDown options based on selected category
            switch UnitMeasurement
                case 'Area'
                    units = {'Square Kilometer', 'Square Meter', 'Square Mile', 'Square Yard', 'Square Foot', 'Square Inch', 'Hectare', 'Acre'};
                case 'Frequency'
                    units = {'Hertz', 'Kilohertz', 'Megahertz', 'Gigahertz'};
                case 'Length'
                    units = {'Kilometer', 'Meter', 'Centimeter', 'Millimeter', 'Mile', 'Yard', 'Foot', 'Inch'};
                case 'Weight'
                    units = {'Tonne', 'Kilogram', 'Gram', 'Milligram', 'Microgram', 'Pound', 'Ounce'};
                case 'Angle'
                    units = {'Degree', 'Radian'};
                case 'Pressure'
                    units = {'Pascal', 'Bar', 'Torr'};
                case 'Time'
                    units = {'Second', 'Minute', 'Hour', 'Day', 'Week', 'Month', 'Year', 'Decade', 'Century'};
                case 'Volume'
                    units = {'Liter', 'Milliliter', 'Cubic Meter', 'Cubic Inch', 'Gallon', 'Cup', 'Teaspoon', 'Tablespoon'};
                case 'Speed'
                    units = {'Miles per Hour', 'Foot per Second', 'Meter per Second', 'Kilometer per Hour', 'Knot'};
                otherwise
                    units = {};
            end
            app.FromDropDown.Items = units;
            app.ToDropDown.Items = units;
            
        end

        % Button pushed function: ConvertButton
        function Convert(app, event)
          % Perform conversion based on selected units and input value
    fromUnit = app.FromDropDown.Value;
    toUnit = app.ToDropDown.Value;
    

    % Implement conversion logic based on fromUnit, toUnit, and inputValue
    conversionFactor = 1; % Default conversion factor

    switch app.UnitMeasurementDropDown.Value
        case 'Area'
            switch app.FromDropDown.Value
                case 'Square Kilometer'
            if strcmp(toUnit, 'Square Kilometer')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Square Meter')
                conversionFactor = 1000000;
            elseif strcmp(toUnit, 'Square Mile')
                conversionFactor = 1/2.58998811;
            elseif strcmp(toUnit, 'Square Yard')
                conversionFactor = 1.1960*1000000;
            elseif strcmp(toUnit, 'Square Foot')
                conversionFactor = 1.0764*10000000;
            elseif strcmp(toUnit, 'Square Inch')
                conversionFactor = 1.5500*1000000000;
            elseif strcmp(toUnit, 'Hectare')
                conversionFactor = 100;
            elseif strcmp(toUnit, 'Acre')
                conversionFactor = 247.105381;
            end
                case 'Square Meter'
            if strcmp(toUnit, 'Square Kilometer')
                conversionFactor = 1/1000000;
            elseif strcmp(toUnit, 'Square Meter')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Square Mile')
                conversionFactor = 1/(2.5900*1000000);
            elseif strcmp(toUnit, 'Square Yard')
                conversionFactor = 1.19599005;
            elseif strcmp(toUnit, 'Square Foot')
                conversionFactor = 10.7639104;
            elseif strcmp(toUnit, 'Square Inch')
                conversionFactor = 1550.0031;
            elseif strcmp(toUnit, 'Hectare')
                conversionFactor = 1/10000;
            elseif strcmp(toUnit, 'Acre')
                conversionFactor = 1/4046.85642;
            end
                case 'Square Mile'
            if strcmp(toUnit, 'Square Kilometer')
                conversionFactor = 2.58998811;
            elseif strcmp(toUnit, 'Square Meter')
                conversionFactor = 2.5900*1000000;
            elseif strcmp(toUnit, 'Square Mile')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Square Yard')
                conversionFactor = 3097600;
            elseif strcmp(toUnit, 'Square Foot')
                conversionFactor = 2.7878*10000000;
            elseif strcmp(toUnit, 'Square Inch')
                conversionFactor = 4.0145*1000000000;
            elseif strcmp(toUnit, 'Hectare')
                conversionFactor = 258.998811;
            elseif strcmp(toUnit, 'Acre')
                conversionFactor = 640;
            end
                case 'Square Yard'
            if strcmp(toUnit, 'Square Kilometer')
                conversionFactor = 1/(1.1960*1000000);
            elseif strcmp(toUnit, 'Square Meter')
                conversionFactor = 1/1.19599005;
            elseif strcmp(toUnit, 'Square Mile')
                conversionFactor = 1/(3.0976*1000000);
            elseif strcmp(toUnit, 'Square Yard')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Square Foot')
                conversionFactor = 9;
            elseif strcmp(toUnit, 'Square Inch')
                conversionFactor = 1296;
            elseif strcmp(toUnit, 'Hectare')
                conversionFactor = 1/11959.9005;
            elseif strcmp(toUnit, 'Acre')
                conversionFactor = 1/4840;
            end
                case 'Square Foot'
            if strcmp(toUnit, 'Square Kilometer')
                conversionFactor = 1/(1.0764*10000000);
            elseif strcmp(toUnit, 'Square Meter')
                conversionFactor = 1/10.7639104;
            elseif strcmp(toUnit, 'Square Mile')
                conversionFactor = 1/(2.7878*10000000);
            elseif strcmp(toUnit, 'Square Yard')
                conversionFactor = 1/9;
            elseif strcmp(toUnit, 'Square Foot')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Square Inch')
                conversionFactor = 144;
            elseif strcmp(toUnit, 'Hectare')
                conversionFactor = 1/107639.104;
            elseif strcmp(toUnit, 'Acre')
                conversionFactor = 1/43560;
            end
                case 'Square Inch'
            if strcmp(toUnit, 'Square Kilometer')
                conversionFactor = 1/(1.5500*1000000000);
            elseif strcmp(toUnit, 'Square Meter')
                conversionFactor = 1/1550.0031;
            elseif strcmp(toUnit, 'Square Mile')
                conversionFactor = 1/(4.0145*1000000000);
            elseif strcmp(toUnit, 'Square Yard')
                conversionFactor = 1/1296;
            elseif strcmp(toUnit, 'Square Foot')
                conversionFactor = 1/144;
            elseif strcmp(toUnit, 'Square Inch')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Hectare')
                conversionFactor = 1/1.5500*10000000;
            elseif strcmp(toUnit, 'Acre')
                conversionFactor = 1/6.2726*1000000;
            end
                case 'Hectare'
            if strcmp(toUnit, 'Square Kilometer')
                conversionFactor = 1/100;
            elseif strcmp(toUnit, 'Square Meter')
                conversionFactor = 10000;
            elseif strcmp(toUnit, 'Square Mile')
                conversionFactor = 1/258.998811;
            elseif strcmp(toUnit, 'Square Yard')
                conversionFactor = 11959.9005;
            elseif strcmp(toUnit, 'Square Foot')
                conversionFactor = 107639.104;
            elseif strcmp(toUnit, 'Square Inch')
                conversionFactor = 1.5500*10000000;
            elseif strcmp(toUnit, 'Hectare')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Acre')
                conversionFactor = 2.47105381;
            end
                case 'Acre'
            if strcmp(toUnit, 'Square Kilometer')
                conversionFactor = 1/247.105381;
            elseif strcmp(toUnit, 'Square Meter')
                conversionFactor = 4046.85642;
            elseif strcmp(toUnit, 'Square Mile')
                conversionFactor = 1/640;
            elseif strcmp(toUnit, 'Square Yard')
                conversionFactor = 4840;
            elseif strcmp(toUnit, 'Square Foot')
                conversionFactor = 43560;
            elseif strcmp(toUnit, 'Square Inch')
                conversionFactor = 6272640;
            elseif strcmp(toUnit, 'Hectare')
                conversionFactor = 1/2.47105381;
            elseif strcmp(toUnit, 'Acre')
                conversionFactor = 1;
            end
            end
        case 'Frequency'
            switch fromUnit
                case 'Hertz'
            if strcmp(toUnit, 'Hertz')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Kilohertz')
                conversionFactor = 1/1000;
            elseif strcmp(toUnit, 'Megahertz')
                conversionFactor = 1/1000000;
            elseif strcmp(toUnit, 'Gigahertz')
                conversionFactor = 1/(1.0000*1000000000);
            end
                case 'Kilohertz'
            if strcmp(toUnit, 'Hertz')
                conversionFactor = 1000;
            elseif strcmp(toUnit, 'Kilohertz')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Megahertz')
                conversionFactor = 1/1000;
            elseif strcmp(toUnit, 'Gigahertz')
                conversionFactor = 1/1000000;
            end
                case 'Megahertz'
            if strcmp(toUnit, 'Hertz')
                conversionFactor = 1000000;
            elseif strcmp(toUnit, 'Kilohertz')
                conversionFactor = 1000;
            elseif strcmp(toUnit, 'Megahertz')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Gigahertz')
                conversionFactor = 1/1000;
            end
                case 'Gigahertz'
            if strcmp(toUnit, 'Hertz')
                conversionFactor = 1000000000;
            elseif strcmp(toUnit, 'Kilohertz')
                conversionFactor = 1000000;
            elseif strcmp(toUnit, 'Megahertz')
                conversionFactor = 1000;
            elseif strcmp(toUnit, 'Gigahertz')
                conversionFactor = 1;
            end
            end
        case 'Length'
            switch fromUnit
                case 'Kilometer'
            if strcmp(toUnit, 'Kilometer')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Meter')
                conversionFactor = 1000;
            elseif strcmp(toUnit, 'Centimeter')
                conversionFactor = 100000;
            elseif strcmp(toUnit, 'Millimeter')
                conversionFactor = 1000000;
            elseif strcmp(toUnit, 'Mile')
                conversionFactor = 1/1.609344;
            elseif strcmp(toUnit, 'Yard')
                conversionFactor = 1093.6133;
            elseif strcmp(toUnit, 'Foot')
                conversionFactor = 3280.8399;
            elseif strcmp(toUnit, 'Inch')
                conversionFactor = 39370.0787;
            end
                case 'Meter'
            if strcmp(toUnit, 'Kilometer')
                conversionFactor = 1/1000;
            elseif strcmp(toUnit, 'Meter')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Centimeter')
                conversionFactor = 100;
            elseif strcmp(toUnit, 'Millimeter')
                conversionFactor = 1000;
            elseif strcmp(toUnit, 'Mile')
                conversionFactor = 1/1609.344;
            elseif strcmp(toUnit, 'Yard')
                conversionFactor = 1.0936133;
            elseif strcmp(toUnit, 'Foot')
                conversionFactor = 3.2808399;
            elseif strcmp(toUnit, 'Inch')
                conversionFactor = 39.3700787;
            end
                case 'Centimeter'
            if strcmp(toUnit, 'Kilometer')
                conversionFactor = 1/100000;
            elseif strcmp(toUnit, 'Meter')
                conversionFactor = 1/100;
            elseif strcmp(toUnit, 'Centimeter')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Millimeter')
                conversionFactor = 10;
            elseif strcmp(toUnit, 'Mile')
                conversionFactor = 1/160934.4;
            elseif strcmp(toUnit, 'Yard')
                conversionFactor = 1/91.44;
            elseif strcmp(toUnit, 'Foot')
                conversionFactor = 1/30.48;
            elseif strcmp(toUnit, 'Inch')
                conversionFactor = 1/2.54;
            end
                case 'Millimeter'
            if strcmp(toUnit, 'Kilometer')
                conversionFactor = 1/1000000;
            elseif strcmp(toUnit, 'Meter')
                conversionFactor = 1/1000;
            elseif strcmp(toUnit, 'Centimeter')
                conversionFactor = 1/10;
            elseif strcmp(toUnit, 'Millimeter')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Mile')
                conversionFactor = 1/(1.6093*1000000);
            elseif strcmp(toUnit, 'Yard')
                conversionFactor = 1/914.4;
            elseif strcmp(toUnit, 'Foot')
                conversionFactor = 1/304.8;
            elseif strcmp(toUnit, 'Inch')
                conversionFactor = 1/25.4;
            end
                case 'Mile'
            if strcmp(toUnit, 'Kilometer')
                conversionFactor = 1.609344;
            elseif strcmp(toUnit, 'Meter')
                conversionFactor = 1609.344;
            elseif strcmp(toUnit, 'Centimeter')
                conversionFactor = 160934.4;
            elseif strcmp(toUnit, 'Millimeter')
                conversionFactor = 1.6093*1000000;
            elseif strcmp(toUnit, 'Mile')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Yard')
                conversionFactor = 1760;
            elseif strcmp(toUnit, 'Foot')
                conversionFactor = 5280;
            elseif strcmp(toUnit, 'Inch')
                conversionFactor = 63360;
            end
                case 'Yard'
            if strcmp(toUnit, 'Kilometer')
                conversionFactor = 1/1093.6133;
            elseif strcmp(toUnit, 'Meter')
                conversionFactor = 1/1.0936133;
            elseif strcmp(toUnit, 'Centimeter')
                conversionFactor = 91.44;
            elseif strcmp(toUnit, 'Millimeter')
                conversionFactor = 914.4;
            elseif strcmp(toUnit, 'Mile')
                conversionFactor = 1/1760;
            elseif strcmp(toUnit, 'Yard')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Foot')
                conversionFactor = 3;
            elseif strcmp(toUnit, 'Inch')
                conversionFactor = 36;
            end
                case 'Foot'
            if strcmp(toUnit, 'Kilometer')
                conversionFactor = 1/3280.8399;
            elseif strcmp(toUnit, 'Meter')
                conversionFactor = 1/3.2808399;
            elseif strcmp(toUnit, 'Centimeter')
                conversionFactor = 30.48;
            elseif strcmp(toUnit, 'Millimeter')
                conversionFactor = 304.8;
            elseif strcmp(toUnit, 'Mile')
                conversionFactor = 1/5280;
            elseif strcmp(toUnit, 'Yard')
                conversionFactor = 1/3;
            elseif strcmp(toUnit, 'Foot')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Inch')
                conversionFactor = 12;
            end
                case 'Inch'
            if strcmp(toUnit, 'Kilometer')
                conversionFactor = 1/39370.0787;
            elseif strcmp(toUnit, 'Meter')
                conversionFactor = 1/39.3700787;
            elseif strcmp(toUnit, 'Centimeter')
                conversionFactor = 2.54;
            elseif strcmp(toUnit, 'Millimeter')
                conversionFactor = 25.4;
            elseif strcmp(toUnit, 'Mile')
                conversionFactor = 1/63360;
            elseif strcmp(toUnit, 'Yard')
                conversionFactor = 1/36;
            elseif strcmp(toUnit, 'Foot')
                conversionFactor = 1/12;
            elseif strcmp(toUnit, 'Inch')
                conversionFactor = 1;
            end        
            end
        case 'Weight'
            switch fromUnit
                case 'Tonne'
            if strcmp(toUnit, 'Tonne')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Kilogram')
                conversionFactor = 1000;
            elseif strcmp(toUnit, 'Gram')
                conversionFactor = 1000000;
            elseif strcmp(toUnit, 'Milligram')
                conversionFactor = 1000000000;
            elseif strcmp(toUnit, 'Microgram')
                conversionFactor = 1000000000000;
            elseif strcmp(toUnit, 'Pound')
                conversionFactor = 2204.62262;
            elseif strcmp(toUnit, 'Ounce')
                conversionFactor = 35273.962;
            end
                case 'Kilogram'
            if strcmp(toUnit, 'Tonne')
                conversionFactor = 1/1000;
            elseif strcmp(toUnit, 'Kilogram')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Gram')
                conversionFactor = 1000;
            elseif strcmp(toUnit, 'Milligram')
                conversionFactor = 1000000;
            elseif strcmp(toUnit, 'Microgram')
                conversionFactor = 1000000000;
            elseif strcmp(toUnit, 'Pound')
                conversionFactor = 2.20462262;
            elseif strcmp(toUnit, 'Ounce')
                conversionFactor = 35.273962;
            end
                case 'Gram'
            if strcmp(toUnit, 'Tonne')
                conversionFactor = 1/1000000;
            elseif strcmp(toUnit, 'Kilogram')
                conversionFactor = 1/1000;
            elseif strcmp(toUnit, 'Gram')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Milligram')
                conversionFactor = 1000;
            elseif strcmp(toUnit, 'Microgram')
                conversionFactor = 1000000;
            elseif strcmp(toUnit, 'Pound')
                conversionFactor = 1/453.59237;
            elseif strcmp(toUnit, 'Ounce')
                conversionFactor = 1/28.3495231;
            end
                case 'Milligram'
            if strcmp(toUnit, 'Tonne')
                conversionFactor = 1/1000000000;
            elseif strcmp(toUnit, 'Kilogram')
                conversionFactor = 1/1000000;
            elseif strcmp(toUnit, 'Gram')
                conversionFactor = 1/1000;
            elseif strcmp(toUnit, 'Milligram')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Microgram')
                conversionFactor = 1000;
            elseif strcmp(toUnit, 'Pound')
                conversionFactor = 1/453592.37;
            elseif strcmp(toUnit, 'Ounce')
                conversionFactor = 1/28349.5231;
            end
                case 'Microgram'
            if strcmp(toUnit, 'Tonne')
                conversionFactor = 1/1000000000000;
            elseif strcmp(toUnit, 'Kilogram')
                conversionFactor = 1/1000000000;
            elseif strcmp(toUnit, 'Gram')
                conversionFactor = 1/1000000;
            elseif strcmp(toUnit, 'Milligram')
                conversionFactor = 1/1000;
            elseif strcmp(toUnit, 'Microgram')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Pound')
                conversionFactor = 1/(4.5359*100000000);
            elseif strcmp(toUnit, 'Ounce')
                conversionFactor = 1/(2.8350*10000000);
            end
                case 'Pound'
            if strcmp(toUnit, 'Tonne')
                conversionFactor = 1/2204.62262;
            elseif strcmp(toUnit, 'Kilogram')
                conversionFactor = 1/2.20462262;
            elseif strcmp(toUnit, 'Gram')
                conversionFactor = 453.59237;
            elseif strcmp(toUnit, 'Milligram')
                conversionFactor = 453592.37;
            elseif strcmp(toUnit, 'Microgram')
                conversionFactor = 4.5359*100000000;
            elseif strcmp(toUnit, 'Pound')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Ounce')
                conversionFactor = 16;
            end        
            end
        case 'Angle'
            switch fromUnit
                case 'Degree'
            if strcmp(toUnit, 'Degree')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Radian')
                conversionFactor = 1/57.2957795;
            end
                case 'Radian'
            if strcmp(toUnit, 'Degree')
                conversionFactor = 57.2957795;
            elseif strcmp(toUnit, 'Radian')
                conversionFactor = 1;
            end
            end
        case 'Pressure'
            switch fromUnit
                case 'Pascal'
            if strcmp(toUnit, 'Pascal')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Bar')
                conversionFactor = 1/100000;
            elseif strcmp(toUnit, 'Torr')
                conversionFactor = 1/133.322368;
            end
                case 'Bar'
            if strcmp(toUnit, 'Pascal')
                conversionFactor = 100000;
            elseif strcmp(toUnit, 'Bar')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Torr')
                conversionFactor = 750.061685;
            end
                case 'Torr'
            if strcmp(toUnit, 'Pascal')
                conversionFactor = 133.322368;
            elseif strcmp(toUnit, 'Bar')
                conversionFactor = 1/750.061685;
            elseif strcmp(toUnit, 'Torr')
                conversionFactor = 1;
            end
            end
        case 'Time'
            switch fromUnit
                case 'Second'
            if strcmp(toUnit, 'Second')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Minute')
                conversionFactor = 1/60;
            elseif strcmp(toUnit, 'Hour')
                conversionFactor = 1/3600;
            elseif strcmp(toUnit, 'Day')
                conversionFactor = 1/86400;
            elseif strcmp(toUnit, 'Week')
                conversionFactor = 1/604800;
            elseif strcmp(toUnit, 'Month')
                conversionFactor = 1/2628000;
            elseif strcmp(toUnit, 'Year')
                conversionFactor = 1/31536000;
            elseif strcmp(toUnit, 'Decade')
                conversionFactor = 1/315360000;
            elseif strcmp(toUnit, 'Century')
                conversionFactor = 1/(3.1536*1000000000);
            end
                case 'Minute'
            if strcmp(toUnit, 'Second')
                conversionFactor = 60;
            elseif strcmp(toUnit, 'Minute')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Hour')
                conversionFactor = 1/60;
            elseif strcmp(toUnit, 'Day')
                conversionFactor = 1/1440;
            elseif strcmp(toUnit, 'Week')
                conversionFactor = 1/10080;
            elseif strcmp(toUnit, 'Month')
                conversionFactor = 1/43800;
            elseif strcmp(toUnit, 'Year')
                conversionFactor = 1/525600;
            elseif strcmp(toUnit, 'Decade')
                conversionFactor = 1/5256000;
            elseif strcmp(toUnit, 'Century')
                conversionFactor = 1/52560000;
            end
                case 'Hour'
            if strcmp(toUnit, 'Second')
                conversionFactor = 3600;
            elseif strcmp(toUnit, 'Minute')
                conversionFactor = 60;
            elseif strcmp(toUnit, 'Hour')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Day')
                conversionFactor = 1/24;
            elseif strcmp(toUnit, 'Week')
                conversionFactor = 1/168;
            elseif strcmp(toUnit, 'Month')
                conversionFactor = 1/730;
            elseif strcmp(toUnit, 'Year')
                conversionFactor = 1/8760;
            elseif strcmp(toUnit, 'Decade')
                conversionFactor = 1/87600;
            elseif strcmp(toUnit, 'Century')
                conversionFactor = 1/876000;
            end
                case 'Day'
            if strcmp(toUnit, 'Second')
                conversionFactor = 86400;
            elseif strcmp(toUnit, 'Minute')
                conversionFactor = 1440;
            elseif strcmp(toUnit, 'Hour')
                conversionFactor = 24;
            elseif strcmp(toUnit, 'Day')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Week')
                conversionFactor = 1/7;
            elseif strcmp(toUnit, 'Month')
                conversionFactor = 1/30.4166667;
            elseif strcmp(toUnit, 'Year')
                conversionFactor = 1/365;
            elseif strcmp(toUnit, 'Decade')
                conversionFactor = 1/3650;
            elseif strcmp(toUnit, 'Century')
                conversionFactor = 1/36500;
            end
                case 'Week'
            if strcmp(toUnit, 'Second')
                conversionFactor = 604800;
            elseif strcmp(toUnit, 'Minute')
                conversionFactor = 10080;
            elseif strcmp(toUnit, 'Hour')
                conversionFactor = 168;
            elseif strcmp(toUnit, 'Day')
                conversionFactor = 7;
            elseif strcmp(toUnit, 'Week')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Month')
                conversionFactor = 1/4.3452381;
            elseif strcmp(toUnit, 'Year')
                conversionFactor = 1/52.1428571;
            elseif strcmp(toUnit, 'Decade')
                conversionFactor = 1/521.428571;
            elseif strcmp(toUnit, 'Century')
                conversionFactor = 1/5214.28571;
            end
                case 'Month'
            if strcmp(toUnit, 'Second')
                conversionFactor = 2628000;
            elseif strcmp(toUnit, 'Minute')
                conversionFactor = 43800;
            elseif strcmp(toUnit, 'Hour')
                conversionFactor = 730;
            elseif strcmp(toUnit, 'Day')
                conversionFactor = 30.4166667;
            elseif strcmp(toUnit, 'Week')
                conversionFactor = 4.3452381;
            elseif strcmp(toUnit, 'Month')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Year')
                conversionFactor = 1/12;
            elseif strcmp(toUnit, 'Decade')
                conversionFactor = 1/120;
            elseif strcmp(toUnit, 'Century')
                conversionFactor = 1/1200;
            end
                case 'Year'
            if strcmp(toUnit, 'Second')
                conversionFactor = 31536000;
            elseif strcmp(toUnit, 'Minute')
                conversionFactor = 525600;
            elseif strcmp(toUnit, 'Hour')
                conversionFactor = 8760;
            elseif strcmp(toUnit, 'Day')
                conversionFactor = 365;
            elseif strcmp(toUnit, 'Week')
                conversionFactor = 52.1428571;
            elseif strcmp(toUnit, 'Month')
                conversionFactor = 12;
            elseif strcmp(toUnit, 'Year')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Decade')
                conversionFactor = 10;
            elseif strcmp(toUnit, 'Century')
                conversionFactor = 100;
            end
                case 'Decade'
            if strcmp(toUnit, 'Second')
                conversionFactor = 315360000;
            elseif strcmp(toUnit, 'Minute')
                conversionFactor = 5256000;
            elseif strcmp(toUnit, 'Hour')
                conversionFactor = 87600;
            elseif strcmp(toUnit, 'Day')
                conversionFactor = 3650;
            elseif strcmp(toUnit, 'Week')
                conversionFactor = 521.428571;
            elseif strcmp(toUnit, 'Month')
                conversionFactor = 120;
            elseif strcmp(toUnit, 'Year')
                conversionFactor = 10;
            elseif strcmp(toUnit, 'Decade')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Century')
                conversionFactor = 1/10;
            end
                case 'Century'
            if strcmp(toUnit, 'Second')
                conversionFactor = 3.1536*1000000000;
            elseif strcmp(toUnit, 'Minute')
                conversionFactor = 52560000;
            elseif strcmp(toUnit, 'Hour')
                conversionFactor = 876000;
            elseif strcmp(toUnit, 'Day')
                conversionFactor = 36500;
            elseif strcmp(toUnit, 'Week')
                conversionFactor = 5214.28571;
            elseif strcmp(toUnit, 'Month')
                conversionFactor = 1200;
            elseif strcmp(toUnit, 'Year')
                conversionFactor = 100;
            elseif strcmp(toUnit, 'Decade')
                conversionFactor = 10;
            elseif strcmp(toUnit, 'Century')
                conversionFactor = 1;
            end
            end
        case 'Volume'
            switch fromUnit
                case 'Liter'
            if strcmp(toUnit, 'Liter')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Milliliter')
                conversionFactor = 1000;
            elseif strcmp(toUnit, 'Cubic Meter')
                conversionFactor = 1/1000;
            elseif strcmp(toUnit, 'Cubic Inch')
                conversionFactor = 61.0237441;
            elseif strcmp(toUnit, 'Gallon')
                conversionFactor = 1/3.78541178;
            elseif strcmp(toUnit, 'Cup')
                conversionFactor = 4.22675284;
            elseif strcmp(toUnit, 'Teaspoon')
                conversionFactor = 202.884136;
            elseif strcmp(toUnit, 'Tablespoon')
                conversionFactor = 67.6280455;
            end
                case 'Milliliter'
            if strcmp(toUnit, 'Liter')
                conversionFactor = 1/1000;
            elseif strcmp(toUnit, 'Milliliter')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Cubic Meter')
                conversionFactor = 1/1000000;
            elseif strcmp(toUnit, 'Cubic Inch')
                conversionFactor = 1/16.387064;
            elseif strcmp(toUnit, 'Gallon')
                conversionFactor = 1/3785.41178;
            elseif strcmp(toUnit, 'Cup')
                conversionFactor = 1/236.588236;
            elseif strcmp(toUnit, 'Teaspoon')
                conversionFactor = 1/4.92892159;
            elseif strcmp(toUnit, 'Tablespoon')
                conversionFactor = 1/14.7867648;
            end
                case 'Cubic Meter'
            if strcmp(toUnit, 'Liter')
                conversionFactor = 1000;
            elseif strcmp(toUnit, 'Milliliter')
                conversionFactor = 1000000;
            elseif strcmp(toUnit, 'Cubic Meter')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Cubic Inch')
                conversionFactor = 61023.7441;
            elseif strcmp(toUnit, 'Gallon')
                conversionFactor = 264.172053;
            elseif strcmp(toUnit, 'Cup')
                conversionFactor = 4226.75284;
            elseif strcmp(toUnit, 'Teaspoon')
                conversionFactor = 202884.136;
            elseif strcmp(toUnit, 'Tablespoon')
                conversionFactor = 67628.0455;
            end
                case 'Cubic Inch'
            if strcmp(toUnit, 'Liter')
                conversionFactor = 1/61.0237441;
            elseif strcmp(toUnit, 'Milliliter')
                conversionFactor = 16.387064;
            elseif strcmp(toUnit, 'Cubic Meter')
                conversionFactor = 1/61023.7441;
            elseif strcmp(toUnit, 'Cubic Inch')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Gallon')
                conversionFactor = 1/231;
            elseif strcmp(toUnit, 'Cup')
                conversionFactor = 1/14.4375;
            elseif strcmp(toUnit, 'Teaspoon')
                conversionFactor = 3.32467533;
            elseif strcmp(toUnit, 'Tablespoon')
                conversionFactor = 1.10822511;
            end
                case 'Gallon'
            if strcmp(toUnit, 'Liter')
                conversionFactor = 3.78541178;
            elseif strcmp(toUnit, 'Milliliter')
                conversionFactor = 3785.41178;
            elseif strcmp(toUnit, 'Cubic Meter')
                conversionFactor = 1/264.172053;
            elseif strcmp(toUnit, 'Cubic Inch')
                conversionFactor = 231;
            elseif strcmp(toUnit, 'Gallon')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Cup')
                conversionFactor = 16;
            elseif strcmp(toUnit, 'Teaspoon')
                conversionFactor = 768;
            elseif strcmp(toUnit, 'Tablespoon')
                conversionFactor = 256;
            end
                case 'Cup'
            if strcmp(toUnit, 'Liter')
                conversionFactor = 1/4.22675284;
            elseif strcmp(toUnit, 'Milliliter')
                conversionFactor = 236.588236;
            elseif strcmp(toUnit, 'Cubic Meter')
                conversionFactor = 1/4226.75284;
            elseif strcmp(toUnit, 'Cubic Inch')
                conversionFactor = 14.4375;
            elseif strcmp(toUnit, 'Gallon')
                conversionFactor = 1/16;
            elseif strcmp(toUnit, 'Cup')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Teaspoon')
                conversionFactor = 48;
            elseif strcmp(toUnit, 'Tablespoon')
                conversionFactor = 16;
            end
                case 'Teaspoon'
            if strcmp(toUnit, 'Liter')
                conversionFactor = 1/202.884136;
            elseif strcmp(toUnit, 'Milliliter')
                conversionFactor = 4.92892159;
            elseif strcmp(toUnit, 'Cubic Meter')
                conversionFactor = 1/202884.136;
            elseif strcmp(toUnit, 'Cubic Inch')
                conversionFactor = 1/3.32467533;
            elseif strcmp(toUnit, 'Gallon')
                conversionFactor = 1/768;
            elseif strcmp(toUnit, 'Cup')
                conversionFactor = 1/48;
            elseif strcmp(toUnit, 'Teaspoon')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Tablespoon')
                conversionFactor = 1/3;
            end
                case 'Tablespoon'
            if strcmp(toUnit, 'Liter')
                conversionFactor = 1/67.6280455;
            elseif strcmp(toUnit, 'Milliliter')
                conversionFactor = 14.7867648;
            elseif strcmp(toUnit, 'Cubic Meter')
                conversionFactor = 1/67628.0455;
            elseif strcmp(toUnit, 'Cubic Inch')
                conversionFactor = 1/1.10822511;
            elseif strcmp(toUnit, 'Gallon')
                conversionFactor = 1/256;
            elseif strcmp(toUnit, 'Cup')
                conversionFactor = 1/16;
            elseif strcmp(toUnit, 'Teaspoon')
                conversionFactor = 3;
            elseif strcmp(toUnit, 'Tablespoon')
                conversionFactor = 1;
            end
            end
        case 'Speed'
            switch fromUnit
                case 'Miles per Hour'
            if strcmp(toUnit, 'Miles per Hour')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Foot per Second')
                conversionFactor = 1.46666667;
            elseif strcmp(toUnit, 'Meter per Second')
                conversionFactor = 1/2.23693629;
            elseif strcmp(toUnit, 'Kilometer per Hour')
                conversionFactor = 1.609344;
            elseif strcmp(toUnit, 'Knot')
                conversionFactor = 1/1.15077945;
            end
                case 'Foot per Second'
            if strcmp(toUnit, 'Miles per Hour')
                conversionFactor = 1/1.46666667;
            elseif strcmp(toUnit, 'Foot per Second')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Meter per Second')
                conversionFactor = 1/3.2808399;
            elseif strcmp(toUnit, 'Kilometer per Hour')
                conversionFactor = 1.09728;
            elseif strcmp(toUnit, 'Knot')
                conversionFactor = 1/1.68780986;
            end
                case 'Meter per Second'
            if strcmp(toUnit, 'Miles per Hour')
                conversionFactor = 2.23693629;
            elseif strcmp(toUnit, 'Foot per Second')
                conversionFactor = 3.2808399;
            elseif strcmp(toUnit, 'Meter per Second')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Kilometer per Hour')
                conversionFactor = 3.6;
            elseif strcmp(toUnit, 'Knot')
                conversionFactor = 1.94384449;
            end
                case 'Kilometer per Hour'
            if strcmp(toUnit, 'Miles per Hour')
                conversionFactor = 1/1.609344;
            elseif strcmp(toUnit, 'Foot per Second')
                conversionFactor = 1/1.09728;
            elseif strcmp(toUnit, 'Meter per Second')
                conversionFactor = 1/3.6;
            elseif strcmp(toUnit, 'Kilometer per Hour')
                conversionFactor = 1;
            elseif strcmp(toUnit, 'Knot')
                conversionFactor = 1/1.852;
            end
                case 'Knot'
            if strcmp(toUnit, 'Miles per Hour')
                conversionFactor = 1.15077945;
            elseif strcmp(toUnit, 'Foot per Second')
                conversionFactor = 1.68780986;
            elseif strcmp(toUnit, 'Meter per Second')
                conversionFactor = 1/1.94384449;
            elseif strcmp(toUnit, 'Kilometer per Hour')
                conversionFactor = 1.852;
            elseif strcmp(toUnit, 'Knot')
                conversionFactor = 1;
            end
            end
    end
     app.ResultEditField.Value = conversionFactor* app.InputEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'white');
            app.UIFigure.Name = 'MATLAB App';
            app.UnitMeasurementDropDownLabel = uilabel(app.UIFigure);
            app.UnitMeasurementDropDownLabel.Text = 'UnitMeasurement';
            app.UnitMeasurementDropDown = uidropdown(app.UIFigure);
            app.UnitMeasurementDropDown.Items = {'-', 'Area', 'Frequency', 'Length', 'Weight', 'Angle', 'Pressure', 'Time', 'Volume', 'Speed'};
            app.UnitMeasurementDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitMeasurementDropDownValueChanged, true);
            app.UnitMeasurementDropDown.Value = '-';
            app.FromDropDownLabel = uilabel(app.UIFigure);
            app.FromDropDownLabel.Text = 'From';
            app.FromDropDown = uidropdown(app.UIFigure);
            app.FromDropDown.Items = {'-'};
            app.FromDropDown.Value = '-';
            app.ToDropDownLabel = uilabel(app.UIFigure);
            app.ToDropDownLabel.Text = 'To';
            app.ToDropDown = uidropdown(app.UIFigure);
            app.ToDropDown.Items = {'-'};
            app.ToDropDown.Value = '-';
            app.InputEditFieldLabel = uilabel(app.UIFigure);
            app.InputEditFieldLabel.Text = 'Input';
            app.InputEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResultEditFieldLabel = uilabel(app.UIFigure);
            app.ResultEditFieldLabel.Text = 'Result';
            app.ResultEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConvertButton = uibutton(app.UIFigure, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @Convert, true);
            app.ConvertButton.Icon = fullfile(pathToMLAPP, 'images.jpeg.jpg');
            app.ConvertButton.Text = 'Convert';
            app.UnitConverterLabel = uilabel(app.UIFigure);
            app.UnitConverterLabel.Text = 'Unit Converter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Converter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end